classdef app1GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        UploadButton  matlab.ui.control.Button
        RightPanel    matlab.ui.container.Panel
        UIAxes2_2     matlab.ui.control.UIAxes
        UIAxes_2      matlab.ui.control.UIAxes
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            global go;
            go=true;
            while(go)
                 [data] = thingSpeakRead(1951496, ...
                "ReadKey", '11KP850VPM3KC2II',...
                'DateRange',[datetime('Dec 8, 2022'),...
                            datetime('Dec 9, 2022')],...
                'OutputFormat','timetable');

lat = thingSpeakRead(1951496,'Fields',1,'ReadKey','3DT0QXNG1B2NVZD1','NumPoints',300,'Timeout',50,'OutputFormat','matrix');
lon = thingSpeakRead(1951496,'Fields',2,'ReadKey','3DT0QXNG1B2NVZD1','NumPoints',300,'Timeout',50,'OutputFormat','matrix');
alt = thingSpeakRead(1951496,'Fields',3,'ReadKey','3DT0QXNG1B2NVZD1','NumPoints',300,'Timeout',50,'OutputFormat','matrix');
acc = thingSpeakRead(1951496,'Fields',5,'ReadKey','3DT0QXNG1B2NVZD1','NumPoints',300,'Timeout',50,'OutputFormat','matrix');
           
            % put a pause on taking the data due to ThingSpeak limitations
            pause(15);

            % data
               %define projected coordinate reference system object (CRS)
               %introduce ESPG code for Cluj-Napoca, Romania(3857)
            proj = projcrs(3857);

            %transform the latitude-longitude coordinates specified by lat 
            % and lon to the x and y map coordinates in the projected 
            % coordinate reference system specified by proj
            
            [x,y]=projfwd(proj,lat,lon);

            plot(app.UIAxes,x)
            plot(app.UIAxes2,y)
            plot(app.UIAxes2_2,alt)
            stem(app.UIAxes_2,acc)
       
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {110, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UploadButton = uibutton(app.LeftPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Latitude')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Longitude')
            xlabel(app.UIAxes2, 'Date')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Acceleration')
            xlabel(app.UIAxes_2, 'Date')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'Altitude')
            xlabel(app.UIAxes2_2, 'Date')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end